classdef DualSpec_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LoadImageTab                  matlab.ui.container.Tab
        BinarySwitch                  matlab.ui.control.Switch
        RotateNegative90Button        matlab.ui.control.Button
        Rotate90Button                matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        MainImage                     matlab.ui.control.UIAxes
        ROIBoundsTab                  matlab.ui.container.Tab
        Blank_BR_Y                    matlab.ui.control.NumericEditField
        BottomRightYEditField_2Label  matlab.ui.control.Label
        Blank_BR_X                    matlab.ui.control.NumericEditField
        BottomRightXEditField_2Label  matlab.ui.control.Label
        Blank_TL_Y                    matlab.ui.control.NumericEditField
        TopLeftYEditField_2Label      matlab.ui.control.Label
        Blank_TL_X                    matlab.ui.control.NumericEditField
        TopLeftXEditField_2Label      matlab.ui.control.Label
        Sample_BR_Y                   matlab.ui.control.NumericEditField
        BottomRightYEditFieldLabel    matlab.ui.control.Label
        Sample_BR_X                   matlab.ui.control.NumericEditField
        BottomRightXEditFieldLabel    matlab.ui.control.Label
        Sample_TL_Y                   matlab.ui.control.NumericEditField
        TopLeftYEditFieldLabel        matlab.ui.control.Label
        Sample_TL_X                   matlab.ui.control.NumericEditField
        TopLeftXEditFieldLabel        matlab.ui.control.Label
        BinarySwitch_ROI_Page         matlab.ui.control.Switch
        BlankSpec                     matlab.ui.control.UIAxes
        SampleSpec                    matlab.ui.control.UIAxes
        IntensityReadingTab           matlab.ui.container.Tab
        IntensityPixelSwitch          matlab.ui.control.Switch
        ReadIntensityButton           matlab.ui.control.Button
        IntensityGraph                matlab.ui.control.UIAxes
        AbsorbanceTab                 matlab.ui.container.Tab
        AbsorbancePixelSwitch         matlab.ui.control.Switch
        CalculateAbsorbanceButton     matlab.ui.control.Button
        AbsorbanceGraph               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Main image and it's binary counter part
        RGBImage;
        grayImage;


        %ROI
            %ROI image
            isolationImage;
            sampleImage;
            blankImage;
        
            %Sample ROI bounds
            sample_TL_X;
            sample_TL_Y;
            sample_BR_X;
            sample_BR_Y;
        
            %Blank ROI bounds
            blank_TL_X;
            blank_TL_Y;
            blank_BR_X;
            blank_BR_Y;


       %Intensity
       sampleIntensity;
       blankIntensity;


       %Asbrobance
       pixels;
       absorbance;
       correctedAbsorbance;


    end
    
    methods (Access = private)
        
        function loadImage(app, imageFile)
                try
                    app.RGBImage = imread(imageFile);
                    app.grayImage = rgb2gray(app.RGBImage);
                    app.isolationImage = app.grayImage;

                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
                
                imagesc(app.MainImage,app.RGBImage);
        end
        
        
        function adjustSpectraIsolation(app)
            
            updateImage(app);
            
            %Sample spectrum boundries and graph axes settings
            if(app.sample_TL_X > 0) && (app.sample_TL_X < app.sample_BR_X) && (app.sample_TL_Y < app.sample_BR_Y)
                app.sampleImage = app.isolationImage(app.sample_TL_Y:1:app.sample_BR_Y, app.sample_TL_X:1:app.sample_BR_X,:);
                app.SampleSpec.YLim = [0, (app.sample_BR_Y - app.sample_TL_Y)];
                app.SampleSpec.XLim = [0, (app.sample_BR_X - app.sample_TL_X)];
            end
            
            %Blank spectrum boundries and graph axes settings
            if(app.blank_TL_X < app.blank_BR_X) && (app.blank_TL_Y < app.blank_BR_Y)
                app.blankImage = app.isolationImage(app.blank_TL_Y:app.blank_BR_Y, app.blank_TL_X:app.blank_BR_X);
                app.BlankSpec.YLim = [0, (app.blank_BR_Y - app.blank_TL_Y)];
                app.BlankSpec.XLim = [0, (app.blank_BR_X - app.blank_TL_X)];
            end
            
            imagesc(app.SampleSpec, app.sampleImage);
            imagesc(app.BlankSpec, app.blankImage);
            
        end
        
        function updateImage(app)
            
            app.isolationImage = app.grayImage;
            
        end
        
        function caluclateAbsorbance(app,Isample, Iblank)
            
            pxl = 1:length(Isample);
            app.pixels = pxl;
            
            slope = (Isample(end)-Isample(1))./(pxl(end)-pxl(1));
            yinter = Isample(1);
            bsamp = slope*pxl+yinter;
            S = Isample-bsamp;
            
            slope = (Iblank(end)-Iblank(1))./(pxl(end)-pxl(1));
            yinter = Iblank(1);
            bblank = slope*pxl+yinter;
            B = Iblank-bblank;
            
            SBdiff = S-B;
            dscale = 1;
            Sc = dscale*SBdiff-S;
            
           
            app.absorbance = real(-log10(S./B));
            app.correctedAbsorbance = real(-log10(Sc./B));
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MainImage.Colormap = gray(256);
            axis(app.MainImage, 'image');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               loadImage(app, fname);
            end
        end

        % Button pushed function: Rotate90Button
        function Rotate90ButtonPushed(app, event)
            app.RGBImage = imrotate(app.RGBImage, -90, "bilinear");
            app.grayImage = imrotate(app.grayImage, -90, "bilinear");
            
            imagesc(app.MainImage,app.RGBImage);
            adjustSpectraIsolation(app);
        end

        % Button pushed function: RotateNegative90Button
        function RotateNegative90ButtonPushed(app, event)
            app.RGBImage = imrotate(app.RGBImage, 90, "bilinear");
            app.grayImage = imrotate(app.grayImage, 90, "bilinear");
            
            imagesc(app.MainImage, app.RGBImage);
            
            
            adjustSpectraIsolation(app);
        end

        % Value changed function: BinarySwitch
        function BinarySwitchValueChanged(app, event)
            if app.BinarySwitch.Value == "RGB"
                imagesc(app.MainImage, app.RGBImage);
            else
                imagesc(app.MainImage, app.grayImage);
            end
        end

        % Value changed function: Sample_TL_X
        function Sample_TL_XValueChanged(app, event)
            value = app.Sample_TL_X.Value;
            
            if (value > 0) && (value < 4000)
                app.sample_TL_X = value;
            else
                uialert(app.UIFigure, ME.message, 'Please enter value larger than 0 and less than the value of the bottom right x');
            end
            
            adjustSpectraIsolation(app);
        end

        % Value changed function: Sample_TL_Y
        function Sample_TL_YValueChanged(app, event)
            value = app.Sample_TL_Y.Value;
            app.sample_TL_Y = value;
            
            adjustSpectraIsolation(app);
        end

        % Value changed function: Sample_BR_X
        function Sample_BR_XValueChanged(app, event)
            value = app.Sample_BR_X.Value;
            app.sample_BR_X = value;
            
            adjustSpectraIsolation(app);
        end

        % Value changed function: Sample_BR_Y
        function Sample_BR_YValueChanged(app, event)
            value = app.Sample_BR_Y.Value;
            app.sample_BR_Y = value;
            
            adjustSpectraIsolation(app);
        end

        % Value changed function: Blank_TL_X
        function Blank_TL_XValueChanged(app, event)
            value = app.Blank_TL_X.Value;
            app.blank_TL_X = value;
            
            adjustSpectraIsolation(app);
        end

        % Value changed function: Blank_TL_Y
        function Blank_TL_YValueChanged(app, event)
            value = app.Blank_TL_Y.Value;
            app.blank_TL_Y = value;
            
            adjustSpectraIsolation(app);
        end

        % Value changed function: Blank_BR_X
        function Blank_BR_XValueChanged(app, event)
            value = app.Blank_BR_X.Value;
            
            app.blank_BR_X = value;
            
            adjustSpectraIsolation(app);
        end

        % Value changed function: Blank_BR_Y
        function Blank_BR_YValueChanged(app, event)
            value = app.Blank_BR_Y.Value;
            app.blank_BR_Y = value;
            
            adjustSpectraIsolation(app);
        end

        % Button pushed function: ReadIntensityButton
        function ReadIntensityButtonPushed(app, event)
            cla(app.IntensityGraph);
            app.sampleIntensity = 0.95 * im2double(mean(app.sampleImage));
            app.blankIntensity = im2double(mean(app.blankImage));
            
            app.IntensityGraph.XLimitMethod = 'padded';
            app.IntensityGraph.YLim = [0 max([app.sampleIntensity app.blankIntensity])];
            
            hold(app.IntensityGraph, "on");
            plot(app.IntensityGraph, app.sampleIntensity, '-r', "Marker",".");
            plot(app.IntensityGraph, app.blankIntensity, '--b', "Marker",".");
            hold(app.IntensityGraph, "off");
        end

        % Button pushed function: CalculateAbsorbanceButton
        function CalculateAbsorbanceButtonPushed(app, event)
            cla(app.AbsorbanceGraph);
            caluclateAbsorbance(app, app.sampleIntensity, app.blankIntensity);
            
            app.AbsorbanceGraph.XLimitMethod = 'padded';
            app.AbsorbanceGraph.YLimitMethod = 'padded';
            
            plot(app.AbsorbanceGraph, app.absorbance, 'r', "Marker",".");
            hold(app.AbsorbanceGraph, "on");
            plot(app.AbsorbanceGraph, app.correctedAbsorbance, 'b', "Marker",".");
            hold(app.AbsorbanceGraph, "off");
        end

        % Value changed function: IntensityPixelSwitch
        function IntensityPixelSwitchValueChanged(app, event)
            value = app.IntensityPixelSwitch.Value;
            
            if value == "Pixels"
                
                cla(app.IntensityGraph);
                app.IntensityGraph.Title.String = "Intensity vs. Pixel Position";
                app.IntensityGraph.XLabel.String = "Pixel Position (px)";
                
                app.IntensityGraph.XLimitMethod = 'padded';
                app.AbsorbanceGraph.YLimitMethod = 'padded';
                
                hold(app.IntensityGraph, "on");
                plot(app.IntensityGraph, app.sampleIntensity, 'r', "Marker",".");
                plot(app.IntensityGraph, app.blankIntensity, 'b', "Marker",".");
                hold(app.IntensityGraph, "off");
                
            else
                
                cla(app.IntensityGraph);
                app.IntensityGraph.Title.String = "Intensity vs. Wavelength";
                app.IntensityGraph.XLabel.String = "Wavelength (nm)";
                
                app.IntensityGraph.XLimitMethod = 'padded';
                app.AbsorbanceGraph.YLimitMethod = 'padded';
                sampleWavelength = 1:length(app.sampleIntensity);
                sampleWavelength = (sampleWavelength * 0.442585242) + 393.9378635;
                
                blankWavelength = 1:length(app.blankIntensity);
                blankWavelength = (blankWavelength * 0.437931524) + 403.4663786;
                
                
                hold(app.IntensityGraph, "on");
                plot(app.IntensityGraph, sampleWavelength, app.sampleIntensity, 'r', "Marker",".");
                plot(app.IntensityGraph, blankWavelength, app.blankIntensity, 'b', "Marker",".");
                hold(app.IntensityGraph, "off");
                
            end
        end

        % Value changed function: AbsorbancePixelSwitch
        function AbsorbancePixelSwitchValueChanged(app, event)
            value = app.AbsorbancePixelSwitch.Value;
            
            if value == "Pixels"
                
                cla(app.AbsorbanceGraph);
                app.AbsorbanceGraph.Title.String = "Absorbance vs. Pixel Position";
                app.AbsorbanceGraph.XLabel.String = "Pixel Position (px)";
                
                app.AbsorbanceGraph.XLimitMethod = 'padded';
                app.AbsorbanceGraph.YLimitMethod = 'padded';
                
                hold(app.AbsorbanceGraph, "on");
                plot(app.AbsorbanceGraph, app.absorbance, 'r', "Marker",".");
                plot(app.AbsorbanceGraph, app.correctedAbsorbance, 'b', "Marker",".");
                hold(app.AbsorbanceGraph, "off");
                
            else
                
                cla(app.AbsorbanceGraph);
                app.AbsorbanceGraph.Title.String = "Absorbance vs. Wavelength";
                app.AbsorbanceGraph.XLabel.String = "Wavelength (nm)";
                
                app.AbsorbanceGraph.XLimitMethod = 'padded';
                app.AbsorbanceGraph.YLimitMethod = 'padded';
                
                absWavelength = 1:length(app.absorbance);
                absWavelength = (absWavelength * 0.442585242) + 393.9378635;
                
                correctedAbsWavelength = 1:length(app.correctedAbsorbance);
                correctedAbsWavelength = (correctedAbsWavelength * 0.437931524) + 403.4663786;
                
                
                hold(app.AbsorbanceGraph, "on");
                plot(app.AbsorbanceGraph, absWavelength, app.absorbance, 'r', "Marker",".");
                plot(app.AbsorbanceGraph, correctedAbsWavelength, app.correctedAbsorbance, 'b', "Marker",".");
                hold(app.AbsorbanceGraph, "off");
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadImageTab = uitab(app.TabGroup);
            app.LoadImageTab.Title = 'Load Image';
            app.MainImage = uiaxes(app.LoadImageTab);
            title(app.MainImage, 'Title')
            xlabel(app.MainImage, 'X')
            ylabel(app.MainImage, 'Y')
            zlabel(app.MainImage, 'Z')
            app.LoadImageButton = uibutton(app.LoadImageTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.Rotate90Button = uibutton(app.LoadImageTab, 'push');
            app.Rotate90Button.ButtonPushedFcn = createCallbackFcn(app, @Rotate90ButtonPushed, true);
            app.Rotate90Button.Text = 'Rotate 90º';
            app.RotateNegative90Button = uibutton(app.LoadImageTab, 'push');
            app.RotateNegative90Button.ButtonPushedFcn = createCallbackFcn(app, @RotateNegative90ButtonPushed, true);
            app.RotateNegative90Button.Text = 'Rotate -90º';
            app.BinarySwitch = uiswitch(app.LoadImageTab, 'slider');
            app.BinarySwitch.Items = {'RGB', 'Binary'};
            app.BinarySwitch.ValueChangedFcn = createCallbackFcn(app, @BinarySwitchValueChanged, true);
            app.BinarySwitch.Value = 'RGB';
            app.ROIBoundsTab = uitab(app.TabGroup);
            app.ROIBoundsTab.Title = 'ROI Bounds';
            app.SampleSpec = uiaxes(app.ROIBoundsTab);
            title(app.SampleSpec, 'Sample')
            xlabel(app.SampleSpec, 'X')
            ylabel(app.SampleSpec, 'Y')
            zlabel(app.SampleSpec, 'Z')
            app.BlankSpec = uiaxes(app.ROIBoundsTab);
            title(app.BlankSpec, 'Blank')
            xlabel(app.BlankSpec, 'X')
            ylabel(app.BlankSpec, 'Y')
            zlabel(app.BlankSpec, 'Z')
            app.BinarySwitch_ROI_Page = uiswitch(app.ROIBoundsTab, 'slider');
            app.BinarySwitch_ROI_Page.Items = {'RGB', 'Binary'};
            app.BinarySwitch_ROI_Page.Value = 'RGB';
            app.TopLeftXEditFieldLabel = uilabel(app.ROIBoundsTab);
            app.TopLeftXEditFieldLabel.Text = 'Top Left X';
            app.Sample_TL_X = uieditfield(app.ROIBoundsTab, 'numeric');
            app.Sample_TL_X.ValueChangedFcn = createCallbackFcn(app, @Sample_TL_XValueChanged, true);
            app.TopLeftYEditFieldLabel = uilabel(app.ROIBoundsTab);
            app.TopLeftYEditFieldLabel.Text = 'Top Left Y';
            app.Sample_TL_Y = uieditfield(app.ROIBoundsTab, 'numeric');
            app.Sample_TL_Y.ValueChangedFcn = createCallbackFcn(app, @Sample_TL_YValueChanged, true);
            app.BottomRightXEditFieldLabel = uilabel(app.ROIBoundsTab);
            app.BottomRightXEditFieldLabel.Text = 'Bottom Right X';
            app.Sample_BR_X = uieditfield(app.ROIBoundsTab, 'numeric');
            app.Sample_BR_X.ValueChangedFcn = createCallbackFcn(app, @Sample_BR_XValueChanged, true);
            app.BottomRightYEditFieldLabel = uilabel(app.ROIBoundsTab);
            app.BottomRightYEditFieldLabel.Text = 'Bottom Right Y';
            app.Sample_BR_Y = uieditfield(app.ROIBoundsTab, 'numeric');
            app.Sample_BR_Y.ValueChangedFcn = createCallbackFcn(app, @Sample_BR_YValueChanged, true);
            app.TopLeftXEditField_2Label = uilabel(app.ROIBoundsTab);
            app.TopLeftXEditField_2Label.Text = 'Top Left X';
            app.Blank_TL_X = uieditfield(app.ROIBoundsTab, 'numeric');
            app.Blank_TL_X.ValueChangedFcn = createCallbackFcn(app, @Blank_TL_XValueChanged, true);
            app.TopLeftYEditField_2Label = uilabel(app.ROIBoundsTab);
            app.TopLeftYEditField_2Label.Text = 'Top Left Y';
            app.Blank_TL_Y = uieditfield(app.ROIBoundsTab, 'numeric');
            app.Blank_TL_Y.ValueChangedFcn = createCallbackFcn(app, @Blank_TL_YValueChanged, true);
            app.BottomRightXEditField_2Label = uilabel(app.ROIBoundsTab);
            app.BottomRightXEditField_2Label.Text = 'Bottom Right X';
            app.Blank_BR_X = uieditfield(app.ROIBoundsTab, 'numeric');
            app.Blank_BR_X.ValueChangedFcn = createCallbackFcn(app, @Blank_BR_XValueChanged, true);
            app.BottomRightYEditField_2Label = uilabel(app.ROIBoundsTab);
            app.BottomRightYEditField_2Label.Text = 'Bottom Right Y';
            app.Blank_BR_Y = uieditfield(app.ROIBoundsTab, 'numeric');
            app.Blank_BR_Y.ValueChangedFcn = createCallbackFcn(app, @Blank_BR_YValueChanged, true);
            app.IntensityReadingTab = uitab(app.TabGroup);
            app.IntensityReadingTab.Title = 'Intensity Reading';
            app.IntensityGraph = uiaxes(app.IntensityReadingTab);
            title(app.IntensityGraph, 'Intensity vs. Pixel Position')
            xlabel(app.IntensityGraph, 'Pixel Position (px)')
            ylabel(app.IntensityGraph, 'Intensity')
            app.ReadIntensityButton = uibutton(app.IntensityReadingTab, 'push');
            app.ReadIntensityButton.ButtonPushedFcn = createCallbackFcn(app, @ReadIntensityButtonPushed, true);
            app.ReadIntensityButton.Text = 'Read Intensity';
            app.IntensityPixelSwitch = uiswitch(app.IntensityReadingTab, 'slider');
            app.IntensityPixelSwitch.Items = {'Pixels', 'Wavelength'};
            app.IntensityPixelSwitch.ValueChangedFcn = createCallbackFcn(app, @IntensityPixelSwitchValueChanged, true);
            app.IntensityPixelSwitch.Value = 'Pixels';
            app.AbsorbanceTab = uitab(app.TabGroup);
            app.AbsorbanceTab.Title = 'Absorbance';
            app.AbsorbanceGraph = uiaxes(app.AbsorbanceTab);
            title(app.AbsorbanceGraph, 'Absorbance vs Pixel Position')
            xlabel(app.AbsorbanceGraph, 'Pixel Position')
            ylabel(app.AbsorbanceGraph, 'Absorbance')
            app.CalculateAbsorbanceButton = uibutton(app.AbsorbanceTab, 'push');
            app.CalculateAbsorbanceButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAbsorbanceButtonPushed, true);
            app.CalculateAbsorbanceButton.Text = 'Calculate Absorbance';
            app.AbsorbancePixelSwitch = uiswitch(app.AbsorbanceTab, 'slider');
            app.AbsorbancePixelSwitch.Items = {'Pixels', 'Wavelength'};
            app.AbsorbancePixelSwitch.ValueChangedFcn = createCallbackFcn(app, @AbsorbancePixelSwitchValueChanged, true);
            app.AbsorbancePixelSwitch.Value = 'Pixels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DualSpec_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end